classdef AcquisitionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AcquisitionUIFigure          matlab.ui.Figure
        AcquisitionLabel             matlab.ui.control.Label
        HelpAcquisitionButton        matlab.ui.control.Button
        ApplyButton                  matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        PulsingSchemeDropDownLabel   matlab.ui.control.Label
        PulsingSchemeDropDown        matlab.ui.control.DropDown
        TimeBetweenPulsesFieldLabel  matlab.ui.control.Label
        TimeBetweenPulsesField       matlab.ui.control.NumericEditField
        NumberOfFramesFieldLabel     matlab.ui.control.Label
        NumberOfFramesField          matlab.ui.control.NumericEditField
        FrameRateFieldLabel          matlab.ui.control.Label
        FrameRateField               matlab.ui.control.NumericEditField
        GenerateFramesButton         matlab.ui.control.Button
        StatusMessageLabel           matlab.ui.control.Label
        StatusLampLabel              matlab.ui.control.Label
        StatusLamp                   matlab.ui.control.Lamp
        ReuseFramesButton            matlab.ui.control.Button
        ReuseCheckBox                matlab.ui.control.CheckBox
        GroundTruthFolderLabel       matlab.ui.control.Label
        GroundTruthFolderField       matlab.ui.control.EditField
        ParalleltrackingSwitchLabel  matlab.ui.control.Label
        ParallelTrackingSwitch       matlab.ui.control.Switch
    end

    
    properties (Access = private)
        CallingApp              % Reference to the main app
        Acquisition             % Struct that holds the acquisition parameters
        Geometry                % Struct that holds the geometry parameters
        Microbubble             % Struct that holds the microbubble parameters
        PATHS
    end
       
    methods (Access = private)
        
        function setValues(app)
            
            % Make sure all pulses from the pulsing scheme fit within a
            % frame:
            dt = app.Acquisition.TimeBetweenPulses;
            N  = app.Acquisition.NumberOfPulses;
            T  = 1/app.Acquisition.FrameRate;
            app.Acquisition.TimeBetweenPulses = min(dt,T/N);
            
            % Set the component values to the acquisition properties
            app.FrameRateField.Value            = app.Acquisition.FrameRate;                    % (Hz)
            app.NumberOfFramesField.Value       = app.Acquisition.NumberOfFrames;
            app.PulsingSchemeDropDown.Value     = app.Acquisition.PulsingScheme;
            app.TimeBetweenPulsesField.Value    = app.Acquisition.TimeBetweenPulses*1e6;        % (us)
            
            app.GroundTruthFolderField.Value    = app.Acquisition.Folder;
            app.ReuseCheckBox.Value             = app.Acquisition.Precompute;
            
            % Parallel computing switch:
            if isfield(app.Acquisition, 'ParallelTracking') && app.Acquisition.ParallelTracking
                app.ParallelTrackingSwitch.Value = 'On';
            else
                app.Acquisition.ParallelTracking = false;
                app.ParallelTrackingSwitch.Value = 'Off';
            end
            
            % Control
            app.StatusLampLabel.Text = 'READY';
            app.StatusMessageLabel.Text = '';
            
        end
        
        function setComponentEnable(app)
            if strcmp(app.Acquisition.PulsingScheme, 'Standard')
                app.TimeBetweenPulsesField.Enable = 'off';
            else
                app.TimeBetweenPulsesField.Enable = 'on';
            end
            
            if app.Acquisition.Precompute
                app.ReuseFramesButton.Enable        = 'on';
                app.GenerateFramesButton.Enable     = 'on';
                app.GroundTruthFolderField.Enable   = 'on';
                app.GroundTruthFolderLabel.Enable   = 'on';
            else
                app.ReuseFramesButton.Enable        = 'off';
                app.GenerateFramesButton.Enable     = 'off';
                app.GroundTruthFolderField.Enable   = 'off';
                app.GroundTruthFolderLabel.Enable   = 'off';
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Make a local copy of the acquisition parameters
            app.Acquisition = app.CallingApp.Acquisition;
            
            app.Geometry    = app.CallingApp.Geometry;
            app.Microbubble = app.CallingApp.Microbubble;
            app.PATHS       = app.CallingApp.PATHS;

            % Set the component values to the acquisition parameters
            setValues(app)
            setComponentEnable(app)
            
        end

        % Close request function: AcquisitionUIFigure
        function AcquisitionUIFigureCloseRequest(app, event)
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Acquisition = reset_acquisition();
            setValues(app) 
            setComponentEnable(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Assign the new acquisition properties to the acquisition
            % properties of the calling app
            app.CallingApp.Acquisition = app.Acquisition;
            
            % Update the fields in the calling app
            setValuesMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Value changed function: FrameRateField
        function FrameRateFieldValueChanged(app, event)
            app.Acquisition.FrameRate = app.FrameRateField.Value;     % (Hz)
            
            % Update field values:
            setValues(app)
        end

        % Value changed function: NumberOfFramesField
        function NumberOfFramesFieldValueChanged(app, event)
            app.Acquisition.NumberOfFrames = app.NumberOfFramesField.Value;
        end

        % Value changed function: PulsingSchemeDropDown
        function PulsingSchemeDropDownValueChanged(app, event)
            app.Acquisition.PulsingScheme = app.PulsingSchemeDropDown.Value;
            setComponentEnable(app)
            
            switch app.Acquisition.PulsingScheme
                case 'Standard'
                    app.Acquisition.NumberOfPulses = 1;
                case 'Pulse inversion'
                    app.Acquisition.NumberOfPulses = 2;
                case 'Amplitude modulation'
                    app.Acquisition.NumberOfPulses = 3;
                case 'Amplitude modulation with pulse inversion'
                    app.Acquisition.NumberOfPulses = 3;   
            end
            
            % Update field values:
            setValues(app)
        end

        % Value changed function: TimeBetweenPulsesField
        function TimeBetweenPulsesFieldValueChanged(app, event)
            app.Acquisition.TimeBetweenPulses = app.TimeBetweenPulsesField.Value*1e-6; % (s)
            
            % Update field values:
            setValues(app)
        end

        % Button pushed function: GenerateFramesButton
        function GenerateFramesButtonPushed(app, event)
            app.StatusLamp.Enable = 'on';
            app.StatusLampLabel.Text = 'BUSY';
            app.StatusMessageLabel.Text = 'Computing microbubble ground truth positions.';
            
            drawnow

            try
                save_folder = datestr(now,'yy_mm_dd_hh_MM');
                save_path = [app.PATHS.GroundTruthPath filesep save_folder];
                
                showStreamlines = false;
                
                generate_streamlines(app.Geometry,app.Microbubble,app.Acquisition,...
                    app.PATHS, save_folder, showStreamlines)
                
                save_path_msg = ['Microbubble ground truth frames saved in:'...
                    newline save_path '.' newline];
            
            catch ME
                assignin('base','ME',ME)
                msgbox(ME.message)
                save_path_msg = '';
                
            end
            
            app.StatusLamp.Enable = 'off';
            app.StatusLampLabel.Text = 'READY';
            app.StatusMessageLabel.Text = '';
            
            if isempty(save_path_msg)
                return
            end
            
            app.Acquisition.Folder = save_folder;
            setValues(app)
            
            disp(save_path_msg)
            plot_msg = 'Do you want to visualise the results?';
            
            % Ask the user to plot the results:
            answer = questdlg([save_path_msg newline plot_msg], 'Plotting', ...
                'Yes','No','Yes');
            
            if isempty(answer)
                % Show app windows again after closing dialog box:
                figure(app.CallingApp.MicrobubbleFlowSimulatorUIFigure)
                figure(app.AcquisitionUIFigure)
            
                return
                
            elseif strcmp(answer,'No')
                return
            end
            
            % Show the results:
            app.StatusLamp.Enable = 'on';
            app.StatusLampLabel.Text = 'BUSY';
            app.StatusMessageLabel.Text = 'Plotting microbubble positions.';
            
            try
                show_microbubble_positions(save_path,app.Geometry,app.PATHS)
                
            catch ME
                assignin('base','ME',ME)
                msgbox('Could not plot the microbubble positions.')
            end
            
            app.StatusLamp.Enable = 'off';
            app.StatusLampLabel.Text = 'READY';
            app.StatusMessageLabel.Text = '';
        end

        % Value changed function: ReuseCheckBox
        function ReuseCheckBoxValueChanged(app, event)
            app.Acquisition.Precompute = app.ReuseCheckBox.Value;
            
            if ~app.Acquisition.Precompute
                app.Acquisition.Folder = '' ;
            end
            
            setComponentEnable(app)
            setValues(app)
        end

        % Button pushed function: ReuseFramesButton
        function ReuseFramesButtonPushed(app, event)
                       
            GroundTruthPath = app.PATHS.GroundTruthPath;
            
            % Get the microbubble frames folder for the simulation:
            folder = uigetdir(GroundTruthPath);
            
            figure(app.CallingApp.MicrobubbleFlowSimulatorUIFigure)
            figure(app.AcquisitionUIFigure)
            
            if folder == 0
                return
            else
                folder = strsplit(folder,filesep);
                folder = folder{end};
            end
            
            app.Acquisition.Folder = folder;
            
            setValues(app)
        end

        % Value changed function: ParallelTrackingSwitch
        function ParallelTrackingSwitchValueChanged(app, event)
            switch app.ParallelTrackingSwitch.Value
                case 'On'
                    app.Acquisition.ParallelTracking = true;
                case 'Off'
                    app.Acquisition.ParallelTracking = false;
            end
            
        end

        % Button pushed function: HelpAcquisitionButton
        function HelpAcquisitionButtonPushed(app, event)
            web(app.CallingApp.Documentation.Acquisition)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AcquisitionUIFigure = uifigure('Visible', 'off');
            app.AcquisitionUIFigure.Name = 'Acquisition';
            app.AcquisitionUIFigure.CloseRequestFcn = createCallbackFcn(app, @AcquisitionUIFigureCloseRequest, true);
            app.AcquisitionLabel = uilabel(app.AcquisitionUIFigure);
            app.AcquisitionLabel.Text = 'Acquisition';
            app.HelpAcquisitionButton = uibutton(app.AcquisitionUIFigure, 'push');
            app.HelpAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @HelpAcquisitionButtonPushed, true);
            app.HelpAcquisitionButton.Text = '?';
            app.ApplyButton = uibutton(app.AcquisitionUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.AcquisitionUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ResetButton = uibutton(app.AcquisitionUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PulsingSchemeDropDownLabel = uilabel(app.AcquisitionUIFigure);
            app.PulsingSchemeDropDownLabel.Text = 'Pulsing scheme';
            app.PulsingSchemeDropDown = uidropdown(app.AcquisitionUIFigure);
            app.PulsingSchemeDropDown.Items = {'Standard', 'Pulse inversion', 'Amplitude modulation', 'Amplitude modulation with pulse inversion'};
            app.PulsingSchemeDropDown.ValueChangedFcn = createCallbackFcn(app, @PulsingSchemeDropDownValueChanged, true);
            app.PulsingSchemeDropDown.Value = 'Standard';
            app.TimeBetweenPulsesFieldLabel = uilabel(app.AcquisitionUIFigure);
            app.TimeBetweenPulsesFieldLabel.Text = 'Time between pulses (μs)';
            app.TimeBetweenPulsesField = uieditfield(app.AcquisitionUIFigure, 'numeric');
            app.TimeBetweenPulsesField.ValueChangedFcn = createCallbackFcn(app, @TimeBetweenPulsesFieldValueChanged, true);
            app.NumberOfFramesFieldLabel = uilabel(app.AcquisitionUIFigure);
            app.NumberOfFramesFieldLabel.Text = {'Number of frames'; ''};
            app.NumberOfFramesField = uieditfield(app.AcquisitionUIFigure, 'numeric');
            app.NumberOfFramesField.Limits = [1 Inf];
            app.NumberOfFramesField.RoundFractionalValues = 'on';
            app.NumberOfFramesField.ValueChangedFcn = createCallbackFcn(app, @NumberOfFramesFieldValueChanged, true);
            app.NumberOfFramesField.Value = 1;
            app.FrameRateFieldLabel = uilabel(app.AcquisitionUIFigure);
            app.FrameRateFieldLabel.Text = 'Frame rate (Hz)';
            app.FrameRateField = uieditfield(app.AcquisitionUIFigure, 'numeric');
            app.FrameRateField.ValueChangedFcn = createCallbackFcn(app, @FrameRateFieldValueChanged, true);
            app.GenerateFramesButton = uibutton(app.AcquisitionUIFigure, 'push');
            app.GenerateFramesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateFramesButtonPushed, true);
            app.GenerateFramesButton.Text = {'Precompute'; 'ground truth'};
            app.StatusMessageLabel = uilabel(app.AcquisitionUIFigure);
            app.StatusMessageLabel.Text = 'Status message';
            app.StatusLampLabel = uilabel(app.AcquisitionUIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.AcquisitionUIFigure);
            app.ReuseFramesButton = uibutton(app.AcquisitionUIFigure, 'push');
            app.ReuseFramesButton.ButtonPushedFcn = createCallbackFcn(app, @ReuseFramesButtonPushed, true);
            app.ReuseFramesButton.Text = {'Reuse'; 'ground truth'};
            app.ReuseCheckBox = uicheckbox(app.AcquisitionUIFigure);
            app.ReuseCheckBox.ValueChangedFcn = createCallbackFcn(app, @ReuseCheckBoxValueChanged, true);
            app.ReuseCheckBox.Text = {'  Reuse or precompute'; '  microbubble ground truth positions'};
            app.GroundTruthFolderLabel = uilabel(app.AcquisitionUIFigure);
            app.GroundTruthFolderLabel.Text = 'Ground truth folder';
            app.GroundTruthFolderField = uieditfield(app.AcquisitionUIFigure, 'text');
            app.GroundTruthFolderField.Editable = 'off';
            app.ParalleltrackingSwitchLabel = uilabel(app.AcquisitionUIFigure);
            app.ParalleltrackingSwitchLabel.Text = 'Parallel tracking';
            app.ParallelTrackingSwitch = uiswitch(app.AcquisitionUIFigure, 'slider');
            app.ParallelTrackingSwitch.ValueChangedFcn = createCallbackFcn(app, @ParallelTrackingSwitchValueChanged, true);
            app.ParallelTrackingSwitch.Tooltip = {'Use parallel computing for tracking the microbubbles'};
            % Show the figure after all components are created
            app.AcquisitionUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AcquisitionGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AcquisitionUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AcquisitionUIFigure)
        end
    end
end