classdef MediumGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MediumUIFigure          matlab.ui.Figure
        MediumLabel             matlab.ui.control.Label
        HelpMediumButton        matlab.ui.control.Button
        ApplyButton             matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
        ResetButton             matlab.ui.control.Button
        SpeedOfSoundFieldLabel  matlab.ui.control.Label
        SpeedOfSoundField       matlab.ui.control.NumericEditField
        DensityFieldLabel       matlab.ui.control.Label
        DensityField            matlab.ui.control.NumericEditField
        TissueDropDownLabel     matlab.ui.control.Label
        TissueDropDown          matlab.ui.control.DropDown
        InhomogeneityLabel      matlab.ui.control.Label
        InhomogeneitySlider     matlab.ui.control.Slider
        InhomogeneityField      matlab.ui.control.NumericEditField
        AttenuationBFieldLabel  matlab.ui.control.Label
        AttenuationBField       matlab.ui.control.NumericEditField
        AttenuationAFieldLabel  matlab.ui.control.Label
        AttenuationAField       matlab.ui.control.NumericEditField
        TissuePropertiesLabel   matlab.ui.control.Label
        AttenuationLabel        matlab.ui.control.Label
        BonAFieldLabel          matlab.ui.control.Label
        BonAField               matlab.ui.control.NumericEditField
        LumenDropDownLabel      matlab.ui.control.Label
        LumenDropDown           matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        CallingApp              % Reference to the main app
        Medium                  % Struct that holds the medium parameters
    end
       
    methods (Access = private)
        
        function setValues(app)
            % Set the component values to the medium properties
            app.TissueDropDown.Value            = app.Medium.Tissue;
            app.InhomogeneityField.Value        = app.Medium.Inhomogeneity*100;
            app.InhomogeneitySlider.Value       = app.Medium.Inhomogeneity*100;
            app.SpeedOfSoundField.Value         = app.Medium.SpeedOfSound;
            app.DensityField.Value              = app.Medium.Density;
            app.BonAField.Value                 = app.Medium.BonA;
            app.AttenuationAField.Value         = app.Medium.AttenuationA;
            app.AttenuationBField.Value         = app.Medium.AttenuationB;
            app.LumenDropDown.Value             = app.Medium.Vessel.Tissue;
        end
        
        function setComponentEnable(app)
            if strcmp(app.Medium.Tissue, 'Custom')
                app.SpeedOfSoundField.Enable        = 'on';
                app.DensityField.Enable             = 'on';
                app.BonAField.Enable                = 'on';
                app.AttenuationAField.Enable        = 'on';
                app.AttenuationBField.Enable        = 'on';
            else
                app.SpeedOfSoundField.Enable        = 'off';
                app.DensityField.Enable             = 'off';
                app.BonAField.Enable                = 'off';
                app.AttenuationAField.Enable        = 'off';
                app.AttenuationBField.Enable        = 'off';
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Make a local copy of the medium parameters
            app.Medium = app.CallingApp.Medium;

            % Set the component values to the medium parameters
            setValues(app)
            setComponentEnable(app)
            
        end

        % Close request function: MediumUIFigure
        function MediumUIFigureCloseRequest(app, event)
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Medium = reset_medium();
            setValues(app) 
            setComponentEnable(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Medium minimum speed of sound for filtering purposes:
            app.Medium = compute_sound_speed_extrema(app.Medium);
            
            % Assign the new medium properties to the medium
            % properties of the calling app
            app.CallingApp.Medium = app.Medium;
            
            % Update domain and simulation parameters
            updateParameters(app.CallingApp)
            
            % Update the fields in the calling app
            setValuesMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Value changed function: TissueDropDown
        function TissueDropDownValueChanged(app, event)
            value = app.TissueDropDown.Value;
            
            app.Medium.Tissue = value;
            app.Medium = assign_medium_properties(app.Medium);
            
            setValues(app)
            setComponentEnable(app)
            
        end

        % Value changed function: InhomogeneitySlider
        function InhomogeneitySliderValueChanged(app, event)
            app.Medium.Inhomogeneity     = app.InhomogeneitySlider.Value/100;
            app.InhomogeneityField.Value = app.InhomogeneitySlider.Value;
            
        end

        % Value changed function: InhomogeneityField
        function InhomogeneityFieldValueChanged(app, event)
            value = app.InhomogeneityField.Value;
            
            % Check if entered value is within limits of slider
            if value < app.InhomogeneitySlider.Limits(1)
                value = app.InhomogeneitySlider.Limits(1);
            elseif value > app.InhomogeneitySlider.Limits(2)
                value = app.InhomogeneitySlider.Limits(2);
            end
            
            app.Medium.Inhomogeneity         = value/100;
            app.InhomogeneityField.Value     = value;
            app.InhomogeneitySlider.Value    = value;
            
        end

        % Value changed function: SpeedOfSoundField
        function SpeedOfSoundFieldValueChanged(app, event)
            value = app.SpeedOfSoundField.Value;
            app.Medium.SpeedOfSound = value;
            
        end

        % Value changed function: DensityField
        function DensityFieldValueChanged(app, event)
            value = app.DensityField.Value;
            app.Medium.Density = value;
            
        end

        % Value changed function: BonAField
        function BonAFieldValueChanged(app, event)
            value = app.BonAField.Value;
            app.Medium.BonA = value;
            
        end

        % Value changed function: AttenuationAField
        function AttenuationAFieldValueChanged(app, event)
            value = app.AttenuationAField.Value;
            app.Medium.AttenuationA = value;
            
        end

        % Value changed function: AttenuationBField
        function AttenuationBFieldValueChanged(app, event)
            value = app.AttenuationBField.Value;
            app.Medium.AttenuationB = value;
            
        end

        % Value changed function: LumenDropDown
        function LumenDropDownValueChanged(app, event)
            Vessel.Tissue = app.LumenDropDown.Value;
            Vessel = assign_medium_properties(Vessel);
            Vessel = assign_liquid_properties(Vessel,Vessel.Tissue);
            
            app.Medium.Vessel = Vessel;
            
        end

        % Button pushed function: HelpMediumButton
        function HelpMediumButtonPushed(app, event)
            web(app.CallingApp.Documentation.Medium)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MediumUIFigure = uifigure('Visible', 'off');
            app.MediumUIFigure.Name = 'Medium';
            app.MediumUIFigure.CloseRequestFcn = createCallbackFcn(app, @MediumUIFigureCloseRequest, true);
            app.MediumLabel = uilabel(app.MediumUIFigure);
            app.MediumLabel.Text = 'Medium';
            app.HelpMediumButton = uibutton(app.MediumUIFigure, 'push');
            app.HelpMediumButton.ButtonPushedFcn = createCallbackFcn(app, @HelpMediumButtonPushed, true);
            app.HelpMediumButton.Text = '?';
            app.ApplyButton = uibutton(app.MediumUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.MediumUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ResetButton = uibutton(app.MediumUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SpeedOfSoundFieldLabel = uilabel(app.MediumUIFigure);
            app.SpeedOfSoundFieldLabel.Text = 'Speed of sound (m/s)';
            app.SpeedOfSoundField = uieditfield(app.MediumUIFigure, 'numeric');
            app.SpeedOfSoundField.ValueChangedFcn = createCallbackFcn(app, @SpeedOfSoundFieldValueChanged, true);
            app.SpeedOfSoundField.Enable = 'off';
            app.DensityFieldLabel = uilabel(app.MediumUIFigure);
            app.DensityFieldLabel.Text = {'Density (kg/m3)'; ''};
            app.DensityField = uieditfield(app.MediumUIFigure, 'numeric');
            app.DensityField.ValueChangedFcn = createCallbackFcn(app, @DensityFieldValueChanged, true);
            app.DensityField.Enable = 'off';
            app.TissueDropDownLabel = uilabel(app.MediumUIFigure);
            app.TissueDropDownLabel.Text = 'Tissue';
            app.TissueDropDown = uidropdown(app.MediumUIFigure);
            app.TissueDropDown.Items = {'General tissue', 'Water', 'Blood', 'Fat', 'Kidney', 'Brain', 'Custom'};
            app.TissueDropDown.ValueChangedFcn = createCallbackFcn(app, @TissueDropDownValueChanged, true);
            app.TissueDropDown.Value = 'General tissue';
            app.InhomogeneityLabel = uilabel(app.MediumUIFigure);
            app.InhomogeneityLabel.Text = 'Medium inhomogeneity (%)';
            app.InhomogeneitySlider = uislider(app.MediumUIFigure);
            app.InhomogeneitySlider.Limits = [0 10];
            app.InhomogeneitySlider.ValueChangedFcn = createCallbackFcn(app, @InhomogeneitySliderValueChanged, true);
            app.InhomogeneityField = uieditfield(app.MediumUIFigure, 'numeric');
            app.InhomogeneityField.ValueChangedFcn = createCallbackFcn(app, @InhomogeneityFieldValueChanged, true);
            app.AttenuationBFieldLabel = uilabel(app.MediumUIFigure);
            app.AttenuationBFieldLabel.Text = 'b';
            app.AttenuationBField = uieditfield(app.MediumUIFigure, 'numeric');
            app.AttenuationBField.ValueChangedFcn = createCallbackFcn(app, @AttenuationBFieldValueChanged, true);
            app.AttenuationBField.Enable = 'off';
            app.AttenuationAFieldLabel = uilabel(app.MediumUIFigure);
            app.AttenuationAFieldLabel.Text = 'a';
            app.AttenuationAField = uieditfield(app.MediumUIFigure, 'numeric');
            app.AttenuationAField.ValueChangedFcn = createCallbackFcn(app, @AttenuationAFieldValueChanged, true);
            app.AttenuationAField.Enable = 'off';
            app.TissuePropertiesLabel = uilabel(app.MediumUIFigure);
            app.TissuePropertiesLabel.Text = 'Tissue properties';
            app.AttenuationLabel = uilabel(app.MediumUIFigure);
            app.AttenuationLabel.Text = {'Attenuation: a*f^b (dB/cm)'; ''};
            app.BonAFieldLabel = uilabel(app.MediumUIFigure);
            app.BonAFieldLabel.Text = 'B/A parameter';
            app.BonAField = uieditfield(app.MediumUIFigure, 'numeric');
            app.BonAField.ValueChangedFcn = createCallbackFcn(app, @BonAFieldValueChanged, true);
            app.BonAField.Enable = 'off';
            app.LumenDropDownLabel = uilabel(app.MediumUIFigure);
            app.LumenDropDownLabel.Text = 'Vessel lumen';
            app.LumenDropDown = uidropdown(app.MediumUIFigure);
            app.LumenDropDown.Items = {'Blood', 'Water'};
            app.LumenDropDown.ValueChangedFcn = createCallbackFcn(app, @LumenDropDownValueChanged, true);
            app.LumenDropDown.Value = 'Blood';
            % Show the figure after all components are created
            app.MediumUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MediumGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MediumUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MediumUIFigure)
        end
    end
end