classdef MicrobubblesGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrobubblesUIFigure            matlab.ui.Figure
        DistributionAxes                matlab.ui.control.UIAxes
        SurfaceTensionAxes              matlab.ui.control.UIAxes
        MicrobubblesLabel               matlab.ui.control.Label
        HelpMicrobubblesButton          matlab.ui.control.Button
        ShellModelDropDownLabel         matlab.ui.control.Label
        ShellModelDropDown              matlab.ui.control.DropDown
        MicrobubbleTypeDropDownLabel    matlab.ui.control.Label
        MicrobubbleTypeDropDown         matlab.ui.control.DropDown
        GasDropDownLabel                matlab.ui.control.Label
        GasDropDown                     matlab.ui.control.DropDown
        RayleighPlessetsolverDropDownLabel  matlab.ui.control.Label
        RayleighPlessetsolverDropDown   matlab.ui.control.DropDown
        AdvancedCheckBox                matlab.ui.control.CheckBox
        GaussianDistributionLabel       matlab.ui.control.Label
        LoadLabel                       matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        NumberOfMicrobubblesFieldLabel  matlab.ui.control.Label
        NumberOfMicrobubblesField       matlab.ui.control.NumericEditField
        ShellviscositykgsLabel          matlab.ui.control.Label
        ShellViscosityField             matlab.ui.control.NumericEditField
        InitialSurfaceTensionFieldLabel  matlab.ui.control.Label
        InitialSurfaceTensionField      matlab.ui.control.NumericEditField
        ShellElasticityFieldLabel       matlab.ui.control.Label
        ShellElasticityField            matlab.ui.control.NumericEditField
        InitialradiusmLabel             matlab.ui.control.Label
        InitialRadiusField              matlab.ui.control.NumericEditField
        PolydispersityIndexFieldLabel   matlab.ui.control.Label
        PolydispersityIndexField        matlab.ui.control.NumericEditField
        LoadSigmaButton                 matlab.ui.control.Button
        LoadSigmaLabel                  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp  % Reference to the main app
        Microbubble % Struct that holds the microbubble properties
    end
    
    methods (Access = private)
        
        function setValues(app)
            % Set the component values to the microbubble properties
            app.MicrobubbleTypeDropDown.Value       = app.Microbubble.Type;
            app.NumberOfMicrobubblesField.Value     = app.Microbubble.Number;
            
            app.InitialRadiusField.Value            = app.Microbubble.Distribution.MeanRadius*1e6;               % Set in um
            app.PolydispersityIndexField.Value      = app.Microbubble.Distribution.PDI;                          % Set in %
            
            app.ShellModelDropDown.Value            = app.Microbubble.Shell.Model;
            app.AdvancedCheckBox.Value              = app.Microbubble.Advanced;
            
            app.ShellElasticityField.Value          = app.Microbubble.Shell.Elasticity;                 % Set in N/m
            app.InitialSurfaceTensionField.Value    = app.Microbubble.Shell.InitialSurfaceTension*1e3;  % Set in mN/m
            app.ShellViscosityField.Value           = app.Microbubble.Shell.Viscosity;
            
            app.GasDropDown.Value                   = app.Microbubble.Gas.Type;
            
        end
        
        function setComponentEnable(app)
            % Control whether components are enabled or not
            
            % Advanced components
            if app.Microbubble.Advanced
                
                if strcmp(app.Microbubble.Shell.Model,'Marmottant')
                    app.ShellElasticityFieldLabel.Enable        = 'on';
                    app.ShellElasticityField.Enable             = 'on';
                else
                    app.ShellElasticityFieldLabel.Enable        = 'off';
                    app.ShellElasticityField.Enable             = 'off';
                end
                
                app.InitialSurfaceTensionField.Enable           = 'on';
                app.InitialSurfaceTensionFieldLabel.Enable      = 'on';
                app.ShellViscosityField.Enable                  = 'on';
                app.ShellviscositykgsLabel.Enable               = 'on';
                app.GasDropDown.Enable                          = 'on';
                app.GasDropDownLabel.Enable                     = 'on';
                app.RayleighPlessetsolverDropDown.Enable        = 'on';
                app.RayleighPlessetsolverDropDownLabel.Enable   = 'on';
            else
                app.ShellElasticityFieldLabel.Enable            = 'off';
                app.ShellElasticityField.Enable                 = 'off';
                app.InitialSurfaceTensionField.Enable           = 'off';
                app.InitialSurfaceTensionFieldLabel.Enable      = 'off';
                app.ShellViscosityField.Enable                  = 'off';
                app.ShellviscositykgsLabel.Enable               = 'off';
                app.GasDropDown.Enable                          = 'off';
                app.GasDropDownLabel.Enable                     = 'off';
                app.RayleighPlessetsolverDropDown.Enable        = 'off';
                app.RayleighPlessetsolverDropDownLabel.Enable   = 'off';
            end
            

            % Properties of a Gaussian distribution
            if strcmp(app.Microbubble.Type,'Gaussian')
                app.InitialRadiusField.Enable                   = 'on';
                app.InitialradiusmLabel.Enable                  = 'on';
                app.PolydispersityIndexField.Enable             = 'on';
                app.PolydispersityIndexFieldLabel.Enable        = 'on';
                app.GaussianDistributionLabel.Enable            = 'on';
            else
                app.InitialRadiusField.Enable                   = 'off';
                app.InitialradiusmLabel.Enable                  = 'off';
                app.PolydispersityIndexField.Enable             = 'off';
                app.PolydispersityIndexFieldLabel.Enable        = 'off';
                app.GaussianDistributionLabel.Enable            = 'off';
                
            end

            % Control whether the load buttons are enabled or not
            if strcmp(app.Microbubble.Type,'Custom')
                app.LoadButton.Enable       = 'on';
                app.LoadLabel.Enable        = 'on';
            else
                app.LoadButton.Enable       = 'off';
                app.LoadLabel.Enable        = 'off';
            end
            
            if strcmp(app.Microbubble.Shell.Model,'Custom')
                app.LoadSigmaButton.Enable  = 'on';
                app.LoadSigmaLabel.Enable   = 'on';
            else
                app.LoadSigmaButton.Enable  = 'off';
                app.LoadSigmaLabel.Enable   = 'off';
            end
            
            if isempty(app.Microbubble.Distribution.Radii) || ...
                    (strcmp(app.Microbubble.Shell.Model,'Custom') && isempty(app.Microbubble.Shell.NormalizedArea))
                
                app.ApplyButton.Enable      = 'off';
            else
                app.ApplyButton.Enable      = 'on';
            end
        end
        
        function updateDistribution(app)
            if strcmp(app.Microbubble.Type,'Gaussian')
                
                [R,P] = compute_gaussian_distribution(app.Microbubble);
                
                app.Microbubble.Distribution.Probabilities = P;
                app.Microbubble.Distribution.Radii = R;
                
            elseif strcmp(app.Microbubble.Type,'BR-14')
                
                [R,P] = compute_polydisperse_distribution();
                
                app.Microbubble.Distribution.Probabilities = P;
                app.Microbubble.Distribution.Radii = R;
                
            elseif strcmp(app.Microbubble.Type,'SonoVue')
                
                [R,P] = compute_polydisperse_distribution();
                
                app.Microbubble.Distribution.Probabilities = P;
                app.Microbubble.Distribution.Radii = R;

            else
                app.Microbubble.Distribution.Probabilities = [];
                app.Microbubble.Distribution.Radii = [];
            end
            
        end
        
        function updateDistributionPlot(app)
                
            P = app.Microbubble.Distribution.Probabilities;
            R = app.Microbubble.Distribution.Radii;
            
            if isempty(R)
                % Remove current curve if new curve undefined
                delete(app.DistributionAxes.Children)
            end
            
            plot(app.DistributionAxes,R*1e6,P,'r')
            
            app.DistributionAxes.YTickLabel = [];
        end

        
        function updateSurfaceTensionPlot(app)
            % Compute the new surface tension curve
            [A_m,sig] = compute_surface_tension(app.Microbubble,...
                app.CallingApp.Medium);

            if isempty(A_m)
                % Remove current surface tension curve if new curve undefined
                delete(app.SurfaceTensionAxes.Children)
            end
            % Plot the surface tension curve
            plot(app.SurfaceTensionAxes,A_m,sig*1e3,'r')
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Make a local copy of the microbubble properties
            app.Microbubble = app.CallingApp.Microbubble;

            % Set the component values to the microbubble properties
            setValues(app)
            
            % Control whether components are enabled or not
            setComponentEnable(app)
            updateDistributionPlot(app)
            updateSurfaceTensionPlot(app)

 
        end

        % Close request function: MicrobubblesUIFigure
        function MicrobubblesUIFigureCloseRequest(app, event)
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            
            % Check if surface tension curve has been assigned:
            if strcmp(app.Microbubble.Shell.Model,'Custom') && ...
                    isempty(app.Microbubble.Shell.FitFile)
                
                msgbox('First load surface tension curve.')
                return
                
            end
            
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Assign the new microbubble properties to the microbubble
            % properties of the calling app
            app.CallingApp.Microbubble = app.Microbubble;
            
            % Update the fields in the calling app
            setValuesMain(app.CallingApp)
            updateDistributionPlotMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)            
        end

        % Value changed function: AdvancedCheckBox
        function AdvancedCheckBoxValueChanged(app, event)
            app.Microbubble.Advanced = app.AdvancedCheckBox.Value;          
            setComponentEnable(app)
        end

        % Value changed function: ShellModelDropDown
        function ShellModelDropDownValueChanged(app, event)
            app.Microbubble.Shell.Model = app.ShellModelDropDown.Value;           
            
            if strcmp(app.Microbubble.Shell.Model,'Segers')
                
                fit = load(app.Microbubble.Shell.FitFileSegers);
                app.Microbubble.Shell.NormalizedArea = fit.A_m;
                app.Microbubble.Shell.SurfaceTension = fit.sig;
                
            elseif strcmp(app.Microbubble.Shell.Model,'Custom') && ...
                    ~isempty(app.Microbubble.Shell.FitFile)
                
                fit = load(app.Microbubble.Shell.FitFile);
                app.Microbubble.Shell.NormalizedArea = fit.A_m;
                app.Microbubble.Shell.SurfaceTension = fit.sig;
                
            elseif strcmp(app.Microbubble.Shell.Model,'Custom')
                app.Microbubble.Shell.NormalizedArea = [];
                app.Microbubble.Shell.SurfaceTension = [];
                
            end
            
            updateSurfaceTensionPlot(app)
            setComponentEnable(app)
        end

        % Value changed function: MicrobubbleTypeDropDown
        function MicrobubbleTypeDropDownValueChanged(app, event)
            app.Microbubble.Type = app.MicrobubbleTypeDropDown.Value;
            
            switch app.Microbubble.Type
                case 'Gaussian'
                    app.Microbubble.Gas.Type = 'Perfluorobutane';
                    app.Microbubble.Gas      = get_gas_properties(app.Microbubble.Gas);
                case 'BR-14'
                    app.Microbubble.Gas.Type = 'Perfluorobutane';
                    app.Microbubble.Gas      = get_gas_properties(app.Microbubble.Gas);
                case 'SonoVue'
                    app.Microbubble.Gas.Type = 'Sulfur hexafluoride';
                    app.Microbubble.Gas      = get_gas_properties(app.Microbubble.Gas);
                otherwise
                    app.Microbubble.Gas.Type = 'Perfluorobutane';
                    app.Microbubble.Gas      = get_gas_properties(app.Microbubble.Gas);
            end
            
            updateDistribution(app)
            setComponentEnable(app)
            setValues(app)
            updateDistributionPlot(app)
        end

        % Value changed function: NumberOfMicrobubblesField
        function NumberOfMicrobubblesFieldValueChanged(app, event)
            app.Microbubble.Number = app.NumberOfMicrobubblesField.Value;            
        end

        % Value changed function: InitialRadiusField
        function InitialRadiusFieldValueChanged(app, event)
            app.Microbubble.Distribution.MeanRadius  = app.InitialRadiusField.Value*1e-6;   
            updateDistribution(app)
            updateDistributionPlot(app)
        end

        % Value changed function: PolydispersityIndexField
        function PolydispersityIndexFieldValueChanged(app, event)
            app.Microbubble.Distribution.PDI = app.PolydispersityIndexField.Value;  
            updateDistribution(app)
            updateDistributionPlot(app)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file,folder] = uigetfile;
            
            % Show app windows again after closing uigetfile window
            figure(app.CallingApp.MicrobubbleFlowSimulatorUIFigure)
            figure(app.MicrobubblesUIFigure)
            
            if file == 0
                return
            end
                
            try 
                Distribution = load([folder file]);
                                   
                app.Microbubble.Distribution.Radii          = Distribution.R;
                app.Microbubble.Distribution.Probabilities  = Distribution.P;
    
                updateDistributionPlot(app)
                setComponentEnable(app)                
            
            catch ME
                assignin('base','ME',ME)
                msgbox('Invalid file.')
            end
            
            
        end

        % Button pushed function: LoadSigmaButton
        function LoadSigmaButtonPushed(app, event)
            [file,folder]=uigetfile;
            
            % Show app windows again after closing uigetfile window
            figure(app.CallingApp.MicrobubbleFlowSimulatorUIFigure)
            figure(app.MicrobubblesUIFigure)
            
            if file == 0
                return
            end
            
            try
                filedir = fullfile(folder,file);
                app.Microbubble.Shell.FitFile = filedir;
                
                % Load the surface tension curve
                fit = load(app.Microbubble.Shell.FitFile);
                app.Microbubble.Shell.NormalizedArea = fit.A_m;
                app.Microbubble.Shell.SurfaceTension = fit.sig;
                
                setComponentEnable(app)
                updateSurfaceTensionPlot(app)
                
            catch ME
                assignin('base','ME',ME)
                msgbox('Invalid file.')
            end            
            
        end

        % Value changed function: ShellElasticityField
        function ShellElasticityFieldValueChanged(app, event)
            app.Microbubble.Shell.Elasticity = app.ShellElasticityField.Value;
            updateSurfaceTensionPlot(app)
            
        end

        % Value changed function: InitialSurfaceTensionField
        function InitialSurfaceTensionFieldValueChanged(app, event)
            app.Microbubble.Shell.InitialSurfaceTension = app.InitialSurfaceTensionField.Value/1e3; % Store in N/m
            updateSurfaceTensionPlot(app)
            
        end

        % Value changed function: ShellViscosityField
        function ShellViscosityFieldValueChanged(app, event)
            app.Microbubble.Shell.Viscosity = app.ShellViscosityField.Value;
            
        end

        % Value changed function: GasDropDown
        function GasDropDownValueChanged(app, event)
            app.Microbubble.Gas.Type = app.GasDropDown.Value;  
            app.Microbubble.Gas      = get_gas_properties(app.Microbubble.Gas);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.Microbubble = reset_microbubble();
            setComponentEnable(app)
            setValues(app)
            updateDistributionPlot(app)
            updateSurfaceTensionPlot(app)
        end

        % Button pushed function: HelpMicrobubblesButton
        function HelpMicrobubblesButtonPushed(app, event)
            web(app.CallingApp.Documentation.Microbubbles)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MicrobubblesUIFigure = uifigure('Visible', 'off');
            app.MicrobubblesUIFigure.Name = 'Microbubbles';
            app.MicrobubblesUIFigure.CloseRequestFcn = createCallbackFcn(app, @MicrobubblesUIFigureCloseRequest, true);
            app.DistributionAxes = uiaxes(app.MicrobubblesUIFigure);
            title(app.DistributionAxes, '')
            xlabel(app.DistributionAxes, 'Radius (μm)')
            ylabel(app.DistributionAxes, 'Probability')
            app.SurfaceTensionAxes = uiaxes(app.MicrobubblesUIFigure);
            title(app.SurfaceTensionAxes, '')
            xlabel(app.SurfaceTensionAxes, 'Normalized shell area')
            ylabel(app.SurfaceTensionAxes, 'Surface tension (mN/m)')
            app.MicrobubblesLabel = uilabel(app.MicrobubblesUIFigure);
            app.MicrobubblesLabel.Text = 'Microbubbles';
            app.HelpMicrobubblesButton = uibutton(app.MicrobubblesUIFigure, 'push');
            app.HelpMicrobubblesButton.ButtonPushedFcn = createCallbackFcn(app, @HelpMicrobubblesButtonPushed, true);
            app.HelpMicrobubblesButton.Text = '?';
            app.ShellModelDropDownLabel = uilabel(app.MicrobubblesUIFigure);
            app.ShellModelDropDownLabel.Text = 'Shell model';
            app.ShellModelDropDown = uidropdown(app.MicrobubblesUIFigure);
            app.ShellModelDropDown.Items = {'Marmottant', 'Segers', 'Custom', ''};
            app.ShellModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ShellModelDropDownValueChanged, true);
            app.ShellModelDropDown.Value = 'Segers';
            app.MicrobubbleTypeDropDownLabel = uilabel(app.MicrobubblesUIFigure);
            app.MicrobubbleTypeDropDownLabel.Text = 'Microbubble type';
            app.MicrobubbleTypeDropDown = uidropdown(app.MicrobubblesUIFigure);
            app.MicrobubbleTypeDropDown.Items = {'SonoVue', 'BR-14', 'Gaussian', 'Custom'};
            app.MicrobubbleTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @MicrobubbleTypeDropDownValueChanged, true);
            app.MicrobubbleTypeDropDown.Value = 'SonoVue';
            app.GasDropDownLabel = uilabel(app.MicrobubblesUIFigure);
            app.GasDropDownLabel.Enable = 'off';
            app.GasDropDownLabel.Text = 'Gas';
            app.GasDropDown = uidropdown(app.MicrobubblesUIFigure);
            app.GasDropDown.Items = {'Sulfur hexafluoride', 'Perfluorobutane', 'Perfluoropropane', ''};
            app.GasDropDown.ValueChangedFcn = createCallbackFcn(app, @GasDropDownValueChanged, true);
            app.GasDropDown.Enable = 'off';
            app.GasDropDown.Value = 'Sulfur hexafluoride';
            app.RayleighPlessetsolverDropDownLabel = uilabel(app.MicrobubblesUIFigure);
            app.RayleighPlessetsolverDropDownLabel.Enable = 'off';
            app.RayleighPlessetsolverDropDownLabel.Text = 'Rayleigh-Plesset solver';
            app.RayleighPlessetsolverDropDown = uidropdown(app.MicrobubblesUIFigure);
            app.RayleighPlessetsolverDropDown.Items = {'Marmottant', ''};
            app.RayleighPlessetsolverDropDown.Enable = 'off';
            app.RayleighPlessetsolverDropDown.Value = 'Marmottant';
            app.AdvancedCheckBox = uicheckbox(app.MicrobubblesUIFigure);
            app.AdvancedCheckBox.ValueChangedFcn = createCallbackFcn(app, @AdvancedCheckBoxValueChanged, true);
            app.AdvancedCheckBox.Text = 'Advanced';
            app.GaussianDistributionLabel = uilabel(app.MicrobubblesUIFigure);
            app.GaussianDistributionLabel.Text = 'Gaussian distribution';
            app.LoadLabel = uilabel(app.MicrobubblesUIFigure);
            app.LoadLabel.Enable = 'off';
            app.LoadLabel.Text = 'Load distribution from file';
            app.LoadButton = uibutton(app.MicrobubblesUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Enable = 'off';
            app.LoadButton.Text = 'Load';
            app.ApplyButton = uibutton(app.MicrobubblesUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.MicrobubblesUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.NumberOfMicrobubblesFieldLabel = uilabel(app.MicrobubblesUIFigure);
            app.NumberOfMicrobubblesFieldLabel.Text = {'Number of'; 'microbubbles'};
            app.NumberOfMicrobubblesField = uieditfield(app.MicrobubblesUIFigure, 'numeric');
            app.NumberOfMicrobubblesField.Limits = [0 Inf];
            app.NumberOfMicrobubblesField.RoundFractionalValues = 'on';
            app.NumberOfMicrobubblesField.ValueChangedFcn = createCallbackFcn(app, @NumberOfMicrobubblesFieldValueChanged, true);
            app.ShellviscositykgsLabel = uilabel(app.MicrobubblesUIFigure);
            app.ShellviscositykgsLabel.Enable = 'off';
            app.ShellviscositykgsLabel.Text = 'Shell viscosity (kg/s)';
            app.ShellViscosityField = uieditfield(app.MicrobubblesUIFigure, 'numeric');
            app.ShellViscosityField.ValueChangedFcn = createCallbackFcn(app, @ShellViscosityFieldValueChanged, true);
            app.ShellViscosityField.Enable = 'off';
            app.InitialSurfaceTensionFieldLabel = uilabel(app.MicrobubblesUIFigure);
            app.InitialSurfaceTensionFieldLabel.Enable = 'off';
            app.InitialSurfaceTensionFieldLabel.Text = 'Initial surface tension (mN/m)';
            app.InitialSurfaceTensionField = uieditfield(app.MicrobubblesUIFigure, 'numeric');
            app.InitialSurfaceTensionField.ValueChangedFcn = createCallbackFcn(app, @InitialSurfaceTensionFieldValueChanged, true);
            app.InitialSurfaceTensionField.Enable = 'off';
            app.ShellElasticityFieldLabel = uilabel(app.MicrobubblesUIFigure);
            app.ShellElasticityFieldLabel.Enable = 'off';
            app.ShellElasticityFieldLabel.Text = {'Shell elasticity (N/m)'; ''};
            app.ShellElasticityField = uieditfield(app.MicrobubblesUIFigure, 'numeric');
            app.ShellElasticityField.ValueChangedFcn = createCallbackFcn(app, @ShellElasticityFieldValueChanged, true);
            app.ShellElasticityField.Enable = 'off';
            app.InitialradiusmLabel = uilabel(app.MicrobubblesUIFigure);
            app.InitialradiusmLabel.Text = 'Initial radius (μm)';
            app.InitialRadiusField = uieditfield(app.MicrobubblesUIFigure, 'numeric');
            app.InitialRadiusField.ValueChangedFcn = createCallbackFcn(app, @InitialRadiusFieldValueChanged, true);
            app.PolydispersityIndexFieldLabel = uilabel(app.MicrobubblesUIFigure);
            app.PolydispersityIndexFieldLabel.Text = 'Polydispersity index (%)';
            app.PolydispersityIndexField = uieditfield(app.MicrobubblesUIFigure, 'numeric');
            app.PolydispersityIndexField.ValueChangedFcn = createCallbackFcn(app, @PolydispersityIndexFieldValueChanged, true);
            app.LoadSigmaButton = uibutton(app.MicrobubblesUIFigure, 'push');
            app.LoadSigmaButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSigmaButtonPushed, true);
            app.LoadSigmaButton.Enable = 'off';
            app.LoadSigmaButton.Text = 'Load';
            app.LoadSigmaLabel = uilabel(app.MicrobubblesUIFigure);
            app.LoadSigmaLabel.Enable = 'off';
            app.LoadSigmaLabel.Text = 'Load surface tension curve';
            app.ResetButton = uibutton(app.MicrobubblesUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.MicrobubblesUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MicrobubblesGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicrobubblesUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrobubblesUIFigure)
        end
    end
end