classdef SimulationParametersGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SimulationParametersUIFigure    matlab.ui.Figure
        SimulationParametersLabel       matlab.ui.control.Label
        HelpSimulationParametersButton  matlab.ui.control.Button
        ApplyButton                     matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        PointsPerWavelengthFieldLabel   matlab.ui.control.Label
        PointsPerWavelengthField        matlab.ui.control.NumericEditField
        CFLFieldLabel                   matlab.ui.control.Label
        CFLField                        matlab.ui.control.NumericEditField
        SamplingRateFieldLabel          matlab.ui.control.Label
        SamplingRateField               matlab.ui.control.NumericEditField
        MemoryRequirementFieldLabel     matlab.ui.control.Label
        MemoryRequirementField          matlab.ui.control.EditField
        RuntimeFieldLabel               matlab.ui.control.Label
        RuntimeField                    matlab.ui.control.EditField
        TransmitfrequencyMHzLabel       matlab.ui.control.Label
        TransmitFrequencyField          matlab.ui.control.NumericEditField
        IndependentVariableButtonGroup  matlab.ui.container.ButtonGroup
        CFLButton                       matlab.ui.control.RadioButton
        SamplingRateButton              matlab.ui.control.RadioButton
        IndependentvariableLabel        matlab.ui.control.Label
        SolverDropDownLabel             matlab.ui.control.Label
        SolverDropDown                  matlab.ui.control.DropDown
        DeviceNumberFieldLabel          matlab.ui.control.Label
        DeviceNumberField               matlab.ui.control.NumericEditField
        HybridSimulationSwitchLabel     matlab.ui.control.Label
        HybridSimulationSwitch          matlab.ui.control.Switch
        InteractionsSpinnerLabel        matlab.ui.control.Label
        InteractionsSpinner             matlab.ui.control.Spinner
        SourcesAndSensorsSwitchLabel    matlab.ui.control.Label
        SourcesAndSensorsSwitch         matlab.ui.control.Switch
        SolverHardwareLabel             matlab.ui.control.Label
        MultiplescatteringLabel         matlab.ui.control.Label
        TransducerSwitchLabel           matlab.ui.control.Label
        TransducerSwitch                matlab.ui.control.Switch
        IntegrationDensityLabel         matlab.ui.control.Label
        IntegrationDensityField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        CallingApp              % Reference to the main app
        SimulationParameters    % Struct that holds the simulation parameters
    end
    
    methods (Access = private)
        
        function setValues(app)
            % Set the component values to the transmit properties
            app.SamplingRateField.Value         = app.SimulationParameters.SamplingRate/1e6;        % Show in MHz
            app.PointsPerWavelengthField.Value  = app.SimulationParameters.PointsPerWavelength;
            app.CFLField.Value                  = app.SimulationParameters.CFL;
            app.TransmitFrequencyField.Value    = app.CallingApp.Transmit.CenterFrequency/1e6;      % Show in MHz
            
            app.InteractionsSpinner.Value       = app.SimulationParameters.NumberOfInteractions;
            app.DeviceNumberField.Value         = app.SimulationParameters.DeviceNumber;
            app.IntegrationDensityField.Value   = app.SimulationParameters.IntegrationDensity;
            
            if app.SimulationParameters.HybridSimulation
                app.HybridSimulationSwitch.Value = 'On';
            else
                app.HybridSimulationSwitch.Value = 'Off';
            end
            
            if app.SimulationParameters.SensorOnGrid
                app.SourcesAndSensorsSwitch.Value = 'On grid';
            else
                app.SourcesAndSensorsSwitch.Value = 'Off grid';
            end
            
            if app.SimulationParameters.TransducerOnGrid
                app.TransducerSwitch.Value = 'On grid';
            else
                app.TransducerSwitch.Value = 'Off grid';
            end
            
            switch app.SimulationParameters.Solver
                case '3D'
                    app.SolverDropDown.Value = 'MATLAB';
                case '3DC'
                    app.SolverDropDown.Value = 'C++/CPU';
                case '3DG'
                    app.SolverDropDown.Value = 'C++/GPU';
            end
            
            switch app.SimulationParameters.IndependentVariable
                case 'CFL'
                    app.CFLButton.Value             = true;
                case 'Sampling rate'
                    app.SamplingRateButton.Value    = true;
            end
            
            % File with coefficients for simulation time estimation:
            beta_coeff_file = fullfile(app.CallingApp.PATHS.Start,'time-estimation','beta_coeff.mat');
            
            % Memory estimate:
            [~, mem] = estimate_memory(...
                app.CallingApp.Acquisition, ...
                app.CallingApp.Geometry, ...
                app.CallingApp.Medium, ...
                app.SimulationParameters, ...
                app.CallingApp.Transmit, ...
                beta_coeff_file);
            
            app.MemoryRequirementField.Value = num2str(mem, '%.1f');
            
            
        end
        
        function setComponentEnable(app)
            
            switch app.SimulationParameters.IndependentVariable
                case 'CFL'
                    app.CFLField.Enable             = 'on';
                    app.SamplingRateField.Enable    = 'off';
                case 'Sampling rate'
                    app.CFLField.Enable             = 'off';
                    app.SamplingRateField.Enable    = 'on';
            end
            
            
            switch app.SimulationParameters.Solver
                case '3D'
                    app.DeviceNumberField.Enable           = 'off';
                    app.MemoryRequirementField.Enable      = 'off';
                    app.MemoryRequirementFieldLabel.Enable = 'off';
                case '3DC'
                    app.DeviceNumberField.Enable           = 'off';
                    app.MemoryRequirementField.Enable      = 'off';
                    app.MemoryRequirementFieldLabel.Enable = 'off';
                case '3DG'
                    app.DeviceNumberField.Enable           = 'on';
                    app.MemoryRequirementField.Enable      = 'on';
                    app.MemoryRequirementFieldLabel.Enable = 'on';
            end
            
        
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            % Store main app in property for CloseRequestFcn to use
            app.CallingApp = mainapp;
            
            % Make a local copy of the simulation parameters
            app.SimulationParameters = app.CallingApp.SimulationParameters;
            
            % Add fields that are missing in older version
            if ~isfield(app.SimulationParameters,'IntegrationDensity')
                app.SimulationParameters.IntegrationDensity = 1;
            end
            
            if ~isfield(app.SimulationParameters,'TransducerOnGrid')
                app.SimulationParameters.TransducerOnGrid = false;
            end

            % Set the component values to the simulation parameters
            setValues(app)
            
            % Control whether components are enabled or not
            setComponentEnable(app)
            
        end

        % Close request function: SimulationParametersUIFigure
        function SimulationParametersUIFigureCloseRequest(app, event)
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.SimulationParameters = reset_simulation_parameters();
            
            f0 = app.CallingApp.Transmit.CenterFrequency;
            app.SimulationParameters = update_simulation_parameters(...
                app.SimulationParameters, app.CallingApp.Medium, f0);
            
            setValues(app)
            setComponentEnable(app)
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Assign the new transmit properties to the transmit
            % properties of the calling app
            app.CallingApp.SimulationParameters = app.SimulationParameters;
            
            % Update domain and simulation parameters
            updateParameters(app.CallingApp)
            
            % Update the fields in the calling app
            setValuesMain(app.CallingApp)
            
            setComponentEnableMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the buttons in main app
            app.CallingApp.Status = 'Ready';
            setComponentEnableMain(app.CallingApp)
            
            % Delete the dialog box 
            delete(app)
        end

        % Value changed function: SamplingRateField
        function SamplingRateFieldValueChanged(app, event)
            app.SimulationParameters.SamplingRate = app.SamplingRateField.Value*1e6;    % Store in Hz    
            
            % Update CFL
            f0 = app.CallingApp.Transmit.CenterFrequency;
            app.SimulationParameters = update_simulation_parameters(...
                app.SimulationParameters, app.CallingApp.Medium, f0);
            
            setValues(app)
            
        end

        % Value changed function: PointsPerWavelengthField
        function PointsPerWavelengthFieldValueChanged(app, event)
            app.SimulationParameters.PointsPerWavelength = app.PointsPerWavelengthField.Value;  
            
            % Update CFL or sampling rate
            f0 = app.CallingApp.Transmit.CenterFrequency;
            app.SimulationParameters = update_simulation_parameters(...
                app.SimulationParameters, app.CallingApp.Medium, f0);
            
            setValues(app)
        end

        % Value changed function: CFLField
        function CFLFieldValueChanged(app, event)
            app.SimulationParameters.CFL = app.CFLField.Value;  
            
            % Update sampling rate
            f0 = app.CallingApp.Transmit.CenterFrequency;
            app.SimulationParameters = update_simulation_parameters(...
                app.SimulationParameters, app.CallingApp.Medium, f0);
            
            setValues(app)
        end

        % Selection changed function: IndependentVariableButtonGroup
        function IndependentVariableButtonGroupSelectionChanged(app, event)
            selectedButton = app.IndependentVariableButtonGroup.SelectedObject;
            
            switch selectedButton.Text
                case 'CFL'
                    app.SimulationParameters.IndependentVariable = 'CFL';
                case 'Sampling rate'
                    app.SimulationParameters.IndependentVariable = 'Sampling rate';
            end
            
            setComponentEnable(app)
            
        end

        % Value changed function: InteractionsSpinner
        function InteractionsSpinnerValueChanged(app, event)
            app.SimulationParameters.NumberOfInteractions = app.InteractionsSpinner.Value;
        end

        % Value changed function: HybridSimulationSwitch
        function HybridSimulationSwitchValueChanged(app, event)
            switch app.HybridSimulationSwitch.Value
                case 'On'
                    app.SimulationParameters.HybridSimulation = true;
                case 'Off'
                    app.SimulationParameters.HybridSimulation = false;
            end
            
        end

        % Value changed function: SourcesAndSensorsSwitch
        function SourcesAndSensorsSwitchValueChanged(app, event)
            switch app.SourcesAndSensorsSwitch.Value
                case 'On grid'
                    app.SimulationParameters.SensorOnGrid = true;
                case 'Off grid'
                    app.SimulationParameters.SensorOnGrid = false;
            end
            
        end

        % Value changed function: SolverDropDown
        function SolverDropDownValueChanged(app, event)
            switch app.SolverDropDown.Value
                case 'MATLAB'
                    app.SimulationParameters.Solver = '3D';
                case 'C++/CPU'
                    app.SimulationParameters.Solver = '3DC';
                case 'C++/GPU'
                    app.SimulationParameters.Solver = '3DG';
            end
            
            setComponentEnable(app)
        end

        % Value changed function: DeviceNumberField
        function DeviceNumberFieldValueChanged(app, event)
            app.SimulationParameters.DeviceNumber = app.DeviceNumberField.Value;
        end

        % Button pushed function: HelpSimulationParametersButton
        function HelpSimulationParametersButtonPushed(app, event)
            web(app.CallingApp.Documentation.SimulationParameters)
        end

        % Value changed function: TransducerSwitch
        function TransducerSwitchValueChanged(app, event)
            switch app.TransducerSwitch.Value
                case 'On grid'
                    app.SimulationParameters.TransducerOnGrid = true;
                case 'Off grid'
                    app.SimulationParameters.TransducerOnGrid = false;
            end
        end

        % Value changed function: IntegrationDensityField
        function IntegrationDensityFieldValueChanged(app, event)
            app.SimulationParameters.IntegrationDensity = app.IntegrationDensityField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SimulationParametersUIFigure = uifigure('Visible', 'off');
            app.SimulationParametersUIFigure.Name = 'Simulation parameters';
            app.SimulationParametersUIFigure.CloseRequestFcn = createCallbackFcn(app, @SimulationParametersUIFigureCloseRequest, true);
            app.SimulationParametersLabel = uilabel(app.SimulationParametersUIFigure);
            app.SimulationParametersLabel.Text = 'Simulation parameters';
            app.HelpSimulationParametersButton = uibutton(app.SimulationParametersUIFigure, 'push');
            app.HelpSimulationParametersButton.ButtonPushedFcn = createCallbackFcn(app, @HelpSimulationParametersButtonPushed, true);
            app.HelpSimulationParametersButton.Text = '?';
            app.ApplyButton = uibutton(app.SimulationParametersUIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.CancelButton = uibutton(app.SimulationParametersUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.ResetButton = uibutton(app.SimulationParametersUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.PointsPerWavelengthFieldLabel = uilabel(app.SimulationParametersUIFigure);
            app.PointsPerWavelengthFieldLabel.Text = 'Points per wavelength';
            app.PointsPerWavelengthField = uieditfield(app.SimulationParametersUIFigure, 'numeric');
            app.PointsPerWavelengthField.ValueChangedFcn = createCallbackFcn(app, @PointsPerWavelengthFieldValueChanged, true);
            app.CFLFieldLabel = uilabel(app.SimulationParametersUIFigure);
            app.CFLFieldLabel.Text = 'CFL';
            app.CFLField = uieditfield(app.SimulationParametersUIFigure, 'numeric');
            app.CFLField.ValueChangedFcn = createCallbackFcn(app, @CFLFieldValueChanged, true);
            app.SamplingRateFieldLabel = uilabel(app.SimulationParametersUIFigure);
            app.SamplingRateFieldLabel.Text = 'Sampling rate (MHz)';
            app.SamplingRateField = uieditfield(app.SimulationParametersUIFigure, 'numeric');
            app.SamplingRateField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateFieldValueChanged, true);
            app.MemoryRequirementFieldLabel = uilabel(app.SimulationParametersUIFigure);
            app.MemoryRequirementFieldLabel.Text = {'Memory requirement'; 'estimate GPU (GB)'};
            app.MemoryRequirementField = uieditfield(app.SimulationParametersUIFigure, 'text');
            app.MemoryRequirementField.Editable = 'off';
            app.RuntimeFieldLabel = uilabel(app.SimulationParametersUIFigure);
            app.RuntimeFieldLabel.Enable = 'off';
            app.RuntimeFieldLabel.Visible = 'off';
            app.RuntimeFieldLabel.Text = 'Runtime estimate';
            app.RuntimeField = uieditfield(app.SimulationParametersUIFigure, 'text');
            app.RuntimeField.Editable = 'off';
            app.RuntimeField.Enable = 'off';
            app.RuntimeField.Visible = 'off';
            app.TransmitfrequencyMHzLabel = uilabel(app.SimulationParametersUIFigure);
            app.TransmitfrequencyMHzLabel.Text = 'Transmit frequency (MHz)';
            app.TransmitFrequencyField = uieditfield(app.SimulationParametersUIFigure, 'numeric');
            app.TransmitFrequencyField.Editable = 'off';
            app.TransmitFrequencyField.Enable = 'off';
            app.IndependentVariableButtonGroup = uibuttongroup(app.SimulationParametersUIFigure);
            app.IndependentVariableButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @IndependentVariableButtonGroupSelectionChanged, true);
            app.CFLButton = uiradiobutton(app.IndependentVariableButtonGroup);
            app.CFLButton.Text = 'CFL';
            app.CFLButton.Value = true;
            app.SamplingRateButton = uiradiobutton(app.IndependentVariableButtonGroup);
            app.SamplingRateButton.Text = 'Sampling rate';
            app.IndependentvariableLabel = uilabel(app.SimulationParametersUIFigure);
            app.IndependentvariableLabel.Text = 'Independent variable';
            app.SolverDropDownLabel = uilabel(app.SimulationParametersUIFigure);
            app.SolverDropDownLabel.Text = 'Solver';
            app.SolverDropDown = uidropdown(app.SimulationParametersUIFigure);
            app.SolverDropDown.Items = {'MATLAB', 'C++/CPU', 'C++/GPU'};
            app.SolverDropDown.ValueChangedFcn = createCallbackFcn(app, @SolverDropDownValueChanged, true);
            app.SolverDropDown.Value = 'C++/GPU';
            app.DeviceNumberFieldLabel = uilabel(app.SimulationParametersUIFigure);
            app.DeviceNumberFieldLabel.Text = 'Device number';
            app.DeviceNumberField = uieditfield(app.SimulationParametersUIFigure, 'numeric');
            app.DeviceNumberField.RoundFractionalValues = 'on';
            app.DeviceNumberField.ValueChangedFcn = createCallbackFcn(app, @DeviceNumberFieldValueChanged, true);
            app.HybridSimulationSwitchLabel = uilabel(app.SimulationParametersUIFigure);
            app.HybridSimulationSwitchLabel.Text = {''; 'Hybrid simulation'};
            app.HybridSimulationSwitch = uiswitch(app.SimulationParametersUIFigure, 'slider');
            app.HybridSimulationSwitch.ValueChangedFcn = createCallbackFcn(app, @HybridSimulationSwitchValueChanged, true);
            app.HybridSimulationSwitch.Value = 'On';
            app.InteractionsSpinnerLabel = uilabel(app.SimulationParametersUIFigure);
            app.InteractionsSpinnerLabel.Text = 'Order of interactions';
            app.InteractionsSpinner = uispinner(app.SimulationParametersUIFigure);
            app.InteractionsSpinner.Limits = [0 Inf];
            app.InteractionsSpinner.RoundFractionalValues = 'on';
            app.InteractionsSpinner.ValueChangedFcn = createCallbackFcn(app, @InteractionsSpinnerValueChanged, true);
            app.SourcesAndSensorsSwitchLabel = uilabel(app.SimulationParametersUIFigure);
            app.SourcesAndSensorsSwitchLabel.Text = {'Microbubble'; 'sources and sensors'};
            app.SourcesAndSensorsSwitch = uiswitch(app.SimulationParametersUIFigure, 'slider');
            app.SourcesAndSensorsSwitch.Items = {'Off grid', 'On grid'};
            app.SourcesAndSensorsSwitch.ValueChangedFcn = createCallbackFcn(app, @SourcesAndSensorsSwitchValueChanged, true);
            app.SourcesAndSensorsSwitch.Value = 'Off grid';
            app.SolverHardwareLabel = uilabel(app.SimulationParametersUIFigure);
            app.SolverHardwareLabel.Text = 'Solver/Hardware';
            app.MultiplescatteringLabel = uilabel(app.SimulationParametersUIFigure);
            app.MultiplescatteringLabel.Text = 'Multiple scattering';
            app.TransducerSwitchLabel = uilabel(app.SimulationParametersUIFigure);
            app.TransducerSwitchLabel.Text = {'Transducer'; 'sources and sensors'};
            app.TransducerSwitch = uiswitch(app.SimulationParametersUIFigure, 'slider');
            app.TransducerSwitch.Items = {'Off grid', 'On grid'};
            app.TransducerSwitch.ValueChangedFcn = createCallbackFcn(app, @TransducerSwitchValueChanged, true);
            app.TransducerSwitch.Value = 'Off grid';
            app.IntegrationDensityLabel = uilabel(app.SimulationParametersUIFigure);
            app.IntegrationDensityLabel.Text = {'Transducer integration'; 'density'};
            app.IntegrationDensityField = uieditfield(app.SimulationParametersUIFigure, 'numeric');
            app.IntegrationDensityField.Limits = [0.25 25];
            app.IntegrationDensityField.ValueChangedFcn = createCallbackFcn(app, @IntegrationDensityFieldValueChanged, true);
            app.IntegrationDensityField.Value = 1;
            % Show the figure after all components are created
            app.SimulationParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SimulationParametersGUI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SimulationParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SimulationParametersUIFigure)
        end
    end
end