classdef MainGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MicrobubbleFlowSimulatorUIFigure  matlab.ui.Figure
        MicrobubblesPanel               matlab.ui.container.Panel
        NumberOfMicrobubblesFieldLabel  matlab.ui.control.Label
        NumberOfMicrobubblesField       matlab.ui.control.EditField
        TypeFieldLabel                  matlab.ui.control.Label
        TypeField                       matlab.ui.control.EditField
        ModifyMicrobubblesButton        matlab.ui.control.Button
        MicrobubblesUIAxes              matlab.ui.control.UIAxes
        MicrobubblesLabel               matlab.ui.control.Label
        HelpMicrobubblesButton          matlab.ui.control.Button
        TransducerPanel                 matlab.ui.container.Panel
        TransducerField                 matlab.ui.control.EditField
        ModifyTransducerButton          matlab.ui.control.Button
        TransducerLabel                 matlab.ui.control.Label
        HelpTransducerButton            matlab.ui.control.Button
        AcquisitionPanel                matlab.ui.container.Panel
        AcquisitionLabel                matlab.ui.control.Label
        HelpAcquisitionButton           matlab.ui.control.Button
        NumberOfFramesFieldLabel        matlab.ui.control.Label
        NumberOfFramesField             matlab.ui.control.NumericEditField
        FrameRateFieldLabel             matlab.ui.control.Label
        FrameRateField                  matlab.ui.control.NumericEditField
        ModifyAcquisitionButton         matlab.ui.control.Button
        PulsingschemeLabel              matlab.ui.control.Label
        PulsingSchemeField              matlab.ui.control.EditField
        RunPanel                        matlab.ui.container.Panel
        LoadFileButton                  matlab.ui.control.Button
        WriteToFileButton               matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        StatusLampLabel                 matlab.ui.control.Label
        StatusLamp                      matlab.ui.control.Lamp
        StatusMessageLabel              matlab.ui.control.Label
        HelpRunButton                   matlab.ui.control.Button
        PressureFieldButton             matlab.ui.control.Button
        ResetStatusButton               matlab.ui.control.Button
        GeometryPanel                   matlab.ui.container.Panel
        ExploreGeometryButton           matlab.ui.control.Button
        LoadGeometryButton              matlab.ui.control.Button
        GeometryLabel                   matlab.ui.control.Label
        HelpGeometryButton              matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        SimulationParametersPanel       matlab.ui.container.Panel
        ModifySimulationParametersButton  matlab.ui.control.Button
        SimulationparametersLabel       matlab.ui.control.Label
        HelpSimulationParametersButton  matlab.ui.control.Button
        SamplingRateFieldLabel          matlab.ui.control.Label
        SamplingRateField               matlab.ui.control.NumericEditField
        PointsPerWavelengthFieldLabel   matlab.ui.control.Label
        PointsPerWavelengthField        matlab.ui.control.NumericEditField
        MemoryRequirementFieldLabel     matlab.ui.control.Label
        MemoryRequirementField          matlab.ui.control.NumericEditField
        CodeFieldLabel                  matlab.ui.control.Label
        CodeField                       matlab.ui.control.EditField
        MediumPanel                     matlab.ui.container.Panel
        TissueField                     matlab.ui.control.EditField
        ModifyMediumButton              matlab.ui.control.Button
        MediumLabel                     matlab.ui.control.Label
        HelpMediumButton                matlab.ui.control.Button
        MediumInhomogeneityLabel        matlab.ui.control.Label
        MediumInhomogeneityField        matlab.ui.control.NumericEditField
        TransmitPulsePanel              matlab.ui.container.Panel
        TransmitPulseUIAxes             matlab.ui.control.UIAxes
        ModifyTransmitButton            matlab.ui.control.Button
        TransmitPulseLabel              matlab.ui.control.Label
        HelpTransmitPulseButton         matlab.ui.control.Button
        MechanicalindexFieldLabel       matlab.ui.control.Label
        MechanicalIndexField            matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MicrobubblesDialog 
        SimulationParametersDialog
        MediumDialog
        TransducerDialog
        AcquisitionDialog
        TransmitDialog   
        GeometryDialog
    end
    
    properties (Access = public)
        PATHS
        Documentation
        Microbubble
        SimulationParameters
        Geometry
        Medium
        Transducer
        Acquisition
        Transmit
        Status
    end
    
   
    methods (Access = public)
        
        function setValuesMain(app)
            % Microbubbles
            app.TypeField.Value                 = app.Microbubble.Type;
            app.NumberOfMicrobubblesField.Value = num2str(app.Microbubble.Number);
            
            % Simulation parameters
            app.SamplingRateField.Value         = app.SimulationParameters.SamplingRate/1e6;    % (MHz)
            app.PointsPerWavelengthField.Value  = app.SimulationParameters.PointsPerWavelength;
            
            switch app.SimulationParameters.Solver
                case '3D'
                    app.CodeField.Value = 'MATLAB';
                case '3DC'
                    app.CodeField.Value = 'C++/CPU';
                case '3DG'
                    app.CodeField.Value = 'C++/GPU';
            end
            
            % File with coefficients for simulation time estimation:
            beta_coeff_file = fullfile(app.PATHS.Start,'time-estimation','beta_coeff.mat');
            
            % Memory estimate:
            [~, mem] = estimate_memory(...
                app.Acquisition, ...
                app.Geometry, ...
                app.Medium, ...
                app.SimulationParameters, ...
                app.Transmit,...
                beta_coeff_file);
            
            app.MemoryRequirementField.Value = mem;
            
            % Medium parameters
            app.TissueField.Value               = app.Medium.Tissue;
            app.MediumInhomogeneityField.Value  = app.Medium.Inhomogeneity*100; % Standard deviation as percentage
            
            % Transducer parameters
            app.TransducerField.Value           = app.Transducer.Type;
            
            % Acquisition
            app.FrameRateField.Value            = app.Acquisition.FrameRate;                    % (Hz)
            app.NumberOfFramesField.Value       = app.Acquisition.NumberOfFrames;
            app.PulsingSchemeField.Value        = app.Acquisition.PulsingScheme;
            
            % Transmit
            app.MechanicalIndexField.Value      = app.Transmit.MechanicalIndex;
            
            % Geometry
            app.Image.ImageSource               = fullfile(app.PATHS.GeometriesPath, ...
                                                           app.Geometry.Folder, ...
                                                           app.Geometry.Visualization.Image);
            
            % Control
            app.StatusLampLabel.Text = 'READY';
            app.StatusMessageLabel.Text = '';
      
        end
        
        function setComponentEnableMain(app)
            
            app.StatusLamp.Enable = 'off';

            switch app.SimulationParameters.Solver
                case '3D'
                    app.MemoryRequirementField.Enable      = 'off';
                    app.MemoryRequirementFieldLabel.Enable = 'off';
                case '3DC'
                    app.MemoryRequirementField.Enable      = 'off';
                    app.MemoryRequirementFieldLabel.Enable = 'off';
                case '3DG'
                    app.MemoryRequirementField.Enable      = 'on';
                    app.MemoryRequirementFieldLabel.Enable = 'on';
            end
            
            switch app.Status
                case 'Ready'
                    app.ModifyMicrobubblesButton.Enable = 'on';
                    app.LoadGeometryButton.Enable       = 'on';
                    app.ExploreGeometryButton.Enable    = 'on';
                    app.ModifyMediumButton.Enable       = 'on';
                    app.ModifyTransducerButton.Enable   = 'on';
                    app.ModifyAcquisitionButton.Enable  = 'on';
                    app.ModifyTransmitButton.Enable     = 'on';
                    app.ModifySimulationParametersButton.Enable = 'on';
                    app.WriteToFileButton.Enable        = 'on';
                    app.LoadFileButton.Enable           = 'on';
                    app.RunButton.Enable                = 'on';
                    app.PressureFieldButton.Enable      = 'on';
                    app.ResetStatusButton.Visible       = 'off';
                case 'Busy'
                    app.ModifyMicrobubblesButton.Enable = 'off';
                    app.LoadGeometryButton.Enable       = 'off';
                    app.ExploreGeometryButton.Enable    = 'off';
                    app.ModifyMediumButton.Enable       = 'off';
                    app.ModifyTransducerButton.Enable   = 'off';
                    app.ModifyAcquisitionButton.Enable  = 'off';
                    app.ModifyTransmitButton.Enable     = 'off';
                    app.ModifySimulationParametersButton.Enable = 'off';
                    app.WriteToFileButton.Enable        = 'off';
                    app.LoadFileButton.Enable           = 'off';
                    app.RunButton.Enable                = 'off';
                    app.PressureFieldButton.Enable      = 'off';
            end
        end
        
        function updateParameters(app)
            % Update domain and simulation parameters
            app.Geometry.Domain.Margin = 2*app.Medium.SpeedOfSound/app.Transmit.CenterFrequency;
            app.Geometry = compute_simulation_domain(app.Geometry, app.Transducer, app.Transmit);
            
            app.SimulationParameters = update_simulation_parameters(...
                app.SimulationParameters, app.Medium, app.Transmit.CenterFrequency);
        end
        
        function updateDistributionPlotMain(app)

            R = app.Microbubble.Distribution.Radii;
            P = app.Microbubble.Distribution.Probabilities;
            plot(app.MicrobubblesUIAxes,R*1e6,P,'r')
            
            app.MicrobubblesUIAxes.YTickLabel = [];
            
        end
        
        function updateTransmitPlotMain(app)
            
            p  = app.Transmit.PressureSignal;
            Fs = app.Transmit.SamplingRate;
            
            % Set up time array:
            N = length(p);
            t = (0:(N-1))/Fs;
                       
            % Plot pressure signal:
            plot(app.TransmitPulseUIAxes,t*1e6,p/1e3,'r')
            t_max = max(t)*1e6; % [us]
            xlim(app.TransmitPulseUIAxes,[0, t_max])
            
        end
        
        function RunLocalPushed(app,sim_mode)
            
            app.Status = 'Busy';
            setComponentEnableMain(app)
            
            app.StatusLamp.Enable = 'on';
            app.StatusLampLabel.Text = 'BUSY';
            app.ResetStatusButton.Visible = 'on';
            drawnow
                        
            % Auto-save file and select simulation type:
            [savedir, savename, folder, status_msg] = run_local_setup(...
                sim_mode, ...
                app.Acquisition, ...
                app.Geometry, ...
                app.Medium, ...
                app.Microbubble, ...
                app.SimulationParameters, ...
                app.Transducer, ...
                app.Transmit, ...
                app.PATHS);
                        
            figure(app.MicrobubbleFlowSimulatorUIFigure)
            
            if isempty(status_msg) || isnumeric(folder)
                app.Status = 'Ready';
                setComponentEnableMain(app)
            
                app.StatusLamp.Enable = 'off';
                app.StatusLampLabel.Text = 'READY';
                app.StatusMessageLabel.Text = '';
                return
            end
                        
            app.StatusMessageLabel.Text = status_msg;
            drawnow
            
            addpath(app.PATHS.AcousticModulePath)
            
            try
                run_local(savedir, savename, folder, sim_mode)
            catch ME
                assignin('base','ME',ME)
                msgbox(ME.message)
            end
            
            rmpath(app.PATHS.AcousticModulePath)
            
            app.StatusLamp.Enable = 'off';
            app.StatusLampLabel.Text = 'READY';
            app.StatusMessageLabel.Text = '';
            
            app.Status = 'Ready';
            setComponentEnableMain(app)
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                       
            % Find full paths to installed modules and data folders:
            app.PATHS = path_setup();
            
            % Add directories to the MATLAB path:            
            addpath(app.PATHS.Start)
            addpath(app.PATHS.GUIfunctions)
            
            addpath(app.PATHS.StreamlineFunctions)
            
            % Initialize the simulation settings:
            app.Microbubble             = reset_microbubble();
            app.Medium                  = reset_medium();
            app.Acquisition             = reset_acquisition();
            app.Transducer              = reset_transducer();

            app.Transmit                = reset_transmit(app.Transducer);
            app.SimulationParameters    = reset_simulation_parameters();
                                        
            app.Geometry                = reset_geometry(app.Geometry,app.PATHS);
            
            app.Status = 'Ready';
            
            % Get a struct with URLs to the online documentation:
            app.Documentation = get_documentation_urls();
            
            updateParameters(app)
            setValuesMain(app)
            setComponentEnableMain(app)
            updateDistributionPlotMain(app)
            updateTransmitPlotMain(app)
        end

        % Button pushed function: ModifyMicrobubblesButton
        function ModifyMicrobubblesButtonPushed(app, event)
            app.Status = 'Busy';
            setComponentEnableMain(app)
            app.MicrobubblesDialog = MicrobubblesGUI(app);
        end

        % Close request function: MicrobubbleFlowSimulatorUIFigure
        function MicrobubbleFlowSimulatorUIFigureCloseRequest(app, event)
            delete(app.MicrobubblesDialog) 
            delete(app.SimulationParametersDialog)
            delete(app.MediumDialog)
            delete(app.TransducerDialog)
            delete(app.TransmitDialog)
            delete(app.GeometryDialog)
            delete(app.AcquisitionDialog)
            
            % Remove path to GUI functions:
            if isfield(app,'PATHS')
                rmpath(app.PATHS.Start)
                rmpath(app.PATHS.GUIfunctions)
                rmpath(app.PATHS.StreamlineFunctions)
            end
            
            delete(app)
            
        end

        % Button pushed function: ModifyTransmitButton
        function ModifyTransmitButtonPushed(app, event)
            app.Status = 'Busy';
            setComponentEnableMain(app)
            app.TransmitDialog = TransmitGUI(app);
        end

        % Button pushed function: ModifySimulationParametersButton
        function ModifySimulationParametersButtonPushed(app, event)
            app.Status = 'Busy';
            setComponentEnableMain(app)
            app.SimulationParametersDialog = SimulationParametersGUI(app);
            
        end

        % Button pushed function: ModifyMediumButton
        function ModifyMediumButtonPushed(app, event)
            app.Status = 'Busy';
            setComponentEnableMain(app)
            app.MediumDialog = MediumGUI(app);
        end

        % Button pushed function: WriteToFileButton
        function WriteToFileButtonPushed(app, event)
            app.Status = 'Busy';
            setComponentEnableMain(app)
            
            write_to_file(app.Microbubble,app.SimulationParameters,app.Geometry,...
                app.Transducer,app.Acquisition,app.Medium,app.Transmit,app.PATHS,[])
            
            app.Status = 'Ready';
            setComponentEnableMain(app)
            
            % Show app window again after closing uigetfile window
            figure(app.MicrobubbleFlowSimulatorUIFigure)
            
        end

        % Button pushed function: ModifyTransducerButton
        function ModifyTransducerButtonPushed(app, event)
            app.Status = 'Busy';
            setComponentEnableMain(app)
            app.TransducerDialog = TransducerGUI(app);
        end

        % Button pushed function: ExploreGeometryButton
        function ExploreGeometryButtonPushed(app, event)
            app.Status = 'Busy';
            setComponentEnableMain(app)
            app.GeometryDialog = GeometryGUI(app);
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            app.Status = 'Busy';
            setComponentEnableMain(app)
            
            [Parameters,status] = load_from_file(app.PATHS);
            
            app.Status = 'Ready';
            setComponentEnableMain(app)
            
            % Show app window again after closing uigetfile window
            figure(app.MicrobubbleFlowSimulatorUIFigure)
            
            if strcmp(status,'notloaded')
                return
            elseif strcmp(status,'invalid')
                msgbox('Invalid file.')
                return
            end
            
            try
                app.Acquisition             = Parameters.Acquisition;
                app.Geometry                = Parameters.Geometry;
                app.Medium                  = Parameters.Medium;
                app.Microbubble             = Parameters.Microbubble; 
                app.SimulationParameters    = Parameters.SimulationParameters;
                app.Transducer              = Parameters.Transducer;
                app.Transmit                = Parameters.Transmit;
                
                setValuesMain(app)
                setComponentEnableMain(app)
                updateDistributionPlotMain(app)
                updateTransmitPlotMain(app)
            catch ME
                assignin('base','ME',ME)
                msgbox('Invalid file.')
            end
        end

        % Button pushed function: LoadGeometryButton
        function LoadGeometryButtonPushed(app, event)
            app.Status = 'Busy';
            setComponentEnableMain(app)
            
            value = uigetdir(app.PATHS.GeometriesPath);
            
            app.Status = 'Ready';
            setComponentEnableMain(app)
            
            % Show app window again after closing uigetdir window
            figure(app.MicrobubbleFlowSimulatorUIFigure)
            
            if value == 0
                return
            end
            
            value = strsplit(value,filesep);
            value = value{end};
            
            try
                GeometryTemp = app.Geometry; % Temporary copy of the geometry properties
                app.Geometry.Folder = value;
                app.Geometry = reset_geometry(app.Geometry,app.PATHS);
                updateParameters(app)
                setValuesMain(app)
            catch ME
                assignin('base','ME',ME)
                msgbox(['Invalid folder. Error:' newline ME.message])
                app.Geometry = GeometryTemp;
                updateParameters(app)
                setValuesMain(app)
            end
            
            
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            sim_mode = 'RF data';
            RunLocalPushed(app, sim_mode)
        end

        % Button pushed function: ModifyAcquisitionButton
        function ModifyAcquisitionButtonPushed(app, event)
            app.Status = 'Busy';
            setComponentEnableMain(app)
            app.AcquisitionDialog = AcquisitionGUI(app);
        end

        % Button pushed function: PressureFieldButton
        function PressureFieldButtonPushed(app, event)
            sim_mode = 'Pressure map';
            RunLocalPushed(app, sim_mode)
        end

        % Button pushed function: ResetStatusButton
        function ResetStatusButtonPushed(app, event)
            app.StatusLamp.Enable = 'off';
            app.StatusLampLabel.Text = 'READY';
            app.StatusMessageLabel.Text = '';
            
            app.Status = 'Ready';
            setComponentEnableMain(app)
        end

        % Button pushed function: HelpAcquisitionButton
        function HelpAcquisitionButtonPushed(app, event)
            web(app.Documentation.Acquisition)
        end

        % Button pushed function: HelpGeometryButton
        function HelpGeometryButtonPushed(app, event)
            web(app.Documentation.Geometry)
        end

        % Button pushed function: HelpMediumButton
        function HelpMediumButtonPushed(app, event)
            web(app.Documentation.Medium)
        end

        % Button pushed function: HelpMicrobubblesButton
        function HelpMicrobubblesButtonPushed(app, event)
            web(app.Documentation.Microbubbles)
        end

        % Button pushed function: HelpSimulationParametersButton
        function HelpSimulationParametersButtonPushed(app, event)
            web(app.Documentation.SimulationParameters)
        end

        % Button pushed function: HelpTransducerButton
        function HelpTransducerButtonPushed(app, event)
            web(app.Documentation.Transducer)
        end

        % Button pushed function: HelpTransmitPulseButton
        function HelpTransmitPulseButtonPushed(app, event)
            web(app.Documentation.Transmit)
        end

        % Button pushed function: HelpRunButton
        function HelpRunButtonPushed(app, event)
            web(app.Documentation.Main)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MicrobubbleFlowSimulatorUIFigure = uifigure('Visible', 'off');
            app.MicrobubbleFlowSimulatorUIFigure.Name = 'Microbubble Flow Simulator';
            app.MicrobubbleFlowSimulatorUIFigure.CloseRequestFcn = createCallbackFcn(app, @MicrobubbleFlowSimulatorUIFigureCloseRequest, true);
            app.MicrobubblesPanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.MicrobubblesPanel.Tooltip = {''};
            app.NumberOfMicrobubblesFieldLabel = uilabel(app.MicrobubblesPanel);
            app.NumberOfMicrobubblesFieldLabel.Text = {'Number of'; 'microbubbles'};
            app.NumberOfMicrobubblesField = uieditfield(app.MicrobubblesPanel, 'text');
            app.NumberOfMicrobubblesField.Editable = 'off';
            app.TypeFieldLabel = uilabel(app.MicrobubblesPanel);
            app.TypeFieldLabel.Text = 'Type';
            app.TypeField = uieditfield(app.MicrobubblesPanel, 'text');
            app.TypeField.Editable = 'off';
            app.ModifyMicrobubblesButton = uibutton(app.MicrobubblesPanel, 'push');
            app.ModifyMicrobubblesButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyMicrobubblesButtonPushed, true);
            app.ModifyMicrobubblesButton.Text = 'Modify';
            app.MicrobubblesUIAxes = uiaxes(app.MicrobubblesPanel);
            title(app.MicrobubblesUIAxes, '')
            xlabel(app.MicrobubblesUIAxes, 'Radius (μm)')
            ylabel(app.MicrobubblesUIAxes, 'Probability')
            app.MicrobubblesLabel = uilabel(app.MicrobubblesPanel);
            app.MicrobubblesLabel.Text = 'Microbubbles';
            app.HelpMicrobubblesButton = uibutton(app.MicrobubblesPanel, 'push');
            app.HelpMicrobubblesButton.ButtonPushedFcn = createCallbackFcn(app, @HelpMicrobubblesButtonPushed, true);
            app.HelpMicrobubblesButton.Text = '?';
            app.TransducerPanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.TransducerField = uieditfield(app.TransducerPanel, 'text');
            app.TransducerField.Editable = 'off';
            app.TransducerField.Value = 'P4-1';
            app.ModifyTransducerButton = uibutton(app.TransducerPanel, 'push');
            app.ModifyTransducerButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyTransducerButtonPushed, true);
            app.ModifyTransducerButton.Text = 'Modify';
            app.TransducerLabel = uilabel(app.TransducerPanel);
            app.TransducerLabel.Text = 'Transducer';
            app.HelpTransducerButton = uibutton(app.TransducerPanel, 'push');
            app.HelpTransducerButton.ButtonPushedFcn = createCallbackFcn(app, @HelpTransducerButtonPushed, true);
            app.HelpTransducerButton.Text = '?';
            app.AcquisitionPanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.AcquisitionLabel = uilabel(app.AcquisitionPanel);
            app.AcquisitionLabel.Text = 'Acquisition';
            app.HelpAcquisitionButton = uibutton(app.AcquisitionPanel, 'push');
            app.HelpAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @HelpAcquisitionButtonPushed, true);
            app.HelpAcquisitionButton.Text = '?';
            app.NumberOfFramesFieldLabel = uilabel(app.AcquisitionPanel);
            app.NumberOfFramesFieldLabel.Text = {'Number of frames'; ''};
            app.NumberOfFramesField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.NumberOfFramesField.Editable = 'off';
            app.FrameRateFieldLabel = uilabel(app.AcquisitionPanel);
            app.FrameRateFieldLabel.Text = 'Frame rate (Hz)';
            app.FrameRateField = uieditfield(app.AcquisitionPanel, 'numeric');
            app.FrameRateField.Editable = 'off';
            app.ModifyAcquisitionButton = uibutton(app.AcquisitionPanel, 'push');
            app.ModifyAcquisitionButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyAcquisitionButtonPushed, true);
            app.ModifyAcquisitionButton.Text = 'Modify';
            app.PulsingschemeLabel = uilabel(app.AcquisitionPanel);
            app.PulsingschemeLabel.Text = 'Pulsing scheme';
            app.PulsingSchemeField = uieditfield(app.AcquisitionPanel, 'text');
            app.PulsingSchemeField.Editable = 'off';
            app.RunPanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.LoadFileButton = uibutton(app.RunPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load settings';
            app.WriteToFileButton = uibutton(app.RunPanel, 'push');
            app.WriteToFileButton.ButtonPushedFcn = createCallbackFcn(app, @WriteToFileButtonPushed, true);
            app.WriteToFileButton.Text = 'Save settings';
            app.RunButton = uibutton(app.RunPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = {'Simulate'; 'RF data'};
            app.StatusLampLabel = uilabel(app.RunPanel);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.RunPanel);
            app.StatusMessageLabel = uilabel(app.RunPanel);
            app.StatusMessageLabel.Text = 'Status message';
            app.HelpRunButton = uibutton(app.RunPanel, 'push');
            app.HelpRunButton.ButtonPushedFcn = createCallbackFcn(app, @HelpRunButtonPushed, true);
            app.HelpRunButton.Text = '?';
            app.PressureFieldButton = uibutton(app.RunPanel, 'push');
            app.PressureFieldButton.ButtonPushedFcn = createCallbackFcn(app, @PressureFieldButtonPushed, true);
            app.PressureFieldButton.Text = {'Simulate '; 'pressure field'};
            app.ResetStatusButton = uibutton(app.RunPanel, 'push');
            app.ResetStatusButton.ButtonPushedFcn = createCallbackFcn(app, @ResetStatusButtonPushed, true);
            app.ResetStatusButton.Text = 'Reset status';
            app.GeometryPanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.ExploreGeometryButton = uibutton(app.GeometryPanel, 'push');
            app.ExploreGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @ExploreGeometryButtonPushed, true);
            app.ExploreGeometryButton.Text = {'Modify'; 'orientation'};
            app.LoadGeometryButton = uibutton(app.GeometryPanel, 'push');
            app.LoadGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @LoadGeometryButtonPushed, true);
            app.LoadGeometryButton.Text = {'Select '; 'geometry'};
            app.GeometryLabel = uilabel(app.GeometryPanel);
            app.GeometryLabel.Text = 'Geometry and flow data';
            app.HelpGeometryButton = uibutton(app.GeometryPanel, 'push');
            app.HelpGeometryButton.ButtonPushedFcn = createCallbackFcn(app, @HelpGeometryButtonPushed, true);
            app.HelpGeometryButton.Text = '?';
            app.Image = uiimage(app.GeometryPanel);
            app.SimulationParametersPanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.ModifySimulationParametersButton = uibutton(app.SimulationParametersPanel, 'push');
            app.ModifySimulationParametersButton.ButtonPushedFcn = createCallbackFcn(app, @ModifySimulationParametersButtonPushed, true);
            app.ModifySimulationParametersButton.Text = 'Modify';
            app.SimulationparametersLabel = uilabel(app.SimulationParametersPanel);
            app.SimulationparametersLabel.Text = 'Simulation parameters';
            app.HelpSimulationParametersButton = uibutton(app.SimulationParametersPanel, 'push');
            app.HelpSimulationParametersButton.ButtonPushedFcn = createCallbackFcn(app, @HelpSimulationParametersButtonPushed, true);
            app.HelpSimulationParametersButton.Text = '?';
            app.SamplingRateFieldLabel = uilabel(app.SimulationParametersPanel);
            app.SamplingRateFieldLabel.Text = 'Sampling rate (MHz)';
            app.SamplingRateField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.SamplingRateField.Editable = 'off';
            app.PointsPerWavelengthFieldLabel = uilabel(app.SimulationParametersPanel);
            app.PointsPerWavelengthFieldLabel.Text = 'Points per wavelength';
            app.PointsPerWavelengthField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.PointsPerWavelengthField.Editable = 'off';
            app.MemoryRequirementFieldLabel = uilabel(app.SimulationParametersPanel);
            app.MemoryRequirementFieldLabel.Enable = 'off';
            app.MemoryRequirementFieldLabel.Text = {'Memory requirement'; 'estimate GPU (GB)'};
            app.MemoryRequirementField = uieditfield(app.SimulationParametersPanel, 'numeric');
            app.MemoryRequirementField.ValueDisplayFormat = '%11.2g';
            app.MemoryRequirementField.Editable = 'off';
            app.MemoryRequirementField.Enable = 'off';
            app.CodeFieldLabel = uilabel(app.SimulationParametersPanel);
            app.CodeFieldLabel.Text = 'Code';
            app.CodeField = uieditfield(app.SimulationParametersPanel, 'text');
            app.CodeField.Editable = 'off';
            app.MediumPanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.TissueField = uieditfield(app.MediumPanel, 'text');
            app.TissueField.Editable = 'off';
            app.ModifyMediumButton = uibutton(app.MediumPanel, 'push');
            app.ModifyMediumButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyMediumButtonPushed, true);
            app.ModifyMediumButton.Text = 'Modify';
            app.MediumLabel = uilabel(app.MediumPanel);
            app.MediumLabel.Text = 'Medium';
            app.HelpMediumButton = uibutton(app.MediumPanel, 'push');
            app.HelpMediumButton.ButtonPushedFcn = createCallbackFcn(app, @HelpMediumButtonPushed, true);
            app.HelpMediumButton.Text = '?';
            app.MediumInhomogeneityLabel = uilabel(app.MediumPanel);
            app.MediumInhomogeneityLabel.Text = {'Medium '; 'inhomogeneity (%)'; ''};
            app.MediumInhomogeneityField = uieditfield(app.MediumPanel, 'numeric');
            app.MediumInhomogeneityField.Editable = 'off';
            app.TransmitPulsePanel = uipanel(app.MicrobubbleFlowSimulatorUIFigure);
            app.TransmitPulseUIAxes = uiaxes(app.TransmitPulsePanel);
            title(app.TransmitPulseUIAxes, '')
            xlabel(app.TransmitPulseUIAxes, 'time (μs)')
            ylabel(app.TransmitPulseUIAxes, 'Pressure (kPa)')
            app.ModifyTransmitButton = uibutton(app.TransmitPulsePanel, 'push');
            app.ModifyTransmitButton.ButtonPushedFcn = createCallbackFcn(app, @ModifyTransmitButtonPushed, true);
            app.ModifyTransmitButton.Text = 'Modify';
            app.TransmitPulseLabel = uilabel(app.TransmitPulsePanel);
            app.TransmitPulseLabel.Text = 'Transmit';
            app.HelpTransmitPulseButton = uibutton(app.TransmitPulsePanel, 'push');
            app.HelpTransmitPulseButton.ButtonPushedFcn = createCallbackFcn(app, @HelpTransmitPulseButtonPushed, true);
            app.HelpTransmitPulseButton.Text = '?';
            app.MechanicalindexFieldLabel = uilabel(app.TransmitPulsePanel);
            app.MechanicalindexFieldLabel.Text = 'Mechanical index';
            app.MechanicalIndexField = uieditfield(app.TransmitPulsePanel, 'numeric');
            app.MechanicalIndexField.ValueDisplayFormat = '%.2f';
            app.MechanicalIndexField.Editable = 'off';
            % Show the figure after all components are created
            app.MicrobubbleFlowSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MicrobubbleFlowSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MicrobubbleFlowSimulatorUIFigure)
        end
    end
end